classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TimeEditField              matlab.ui.control.NumericEditField
        TimeEditFieldLabel         matlab.ui.control.Label
        CFEditField                matlab.ui.control.NumericEditField
        CFEditFieldLabel           matlab.ui.control.Label
        TrajectoryDropDown         matlab.ui.control.DropDown
        TrajectoryDropDownLabel    matlab.ui.control.Label
        SendButtonGroup            matlab.ui.container.ButtonGroup
        PointsButton               matlab.ui.control.RadioButton
        TrajectoryButton           matlab.ui.control.RadioButton
        LandButton                 matlab.ui.control.Button
        CheckBox_10                matlab.ui.control.CheckBox
        CheckBox_9                 matlab.ui.control.CheckBox
        CheckBox_8                 matlab.ui.control.CheckBox
        CheckBox_7                 matlab.ui.control.CheckBox
        CheckBox_6                 matlab.ui.control.CheckBox
        CheckBox_5                 matlab.ui.control.CheckBox
        CheckBox_4                 matlab.ui.control.CheckBox
        CheckBox_3                 matlab.ui.control.CheckBox
        CheckBox_2                 matlab.ui.control.CheckBox
        CFEnabledLabel             matlab.ui.control.Label
        CheckBox                   matlab.ui.control.CheckBox
        Tree                       matlab.ui.container.Tree
        GOButton                   matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
        Lamp                       matlab.ui.control.Lamp
        ConnectButton              matlab.ui.control.Button
        ROBOTATCRAZYSWARMGUILabel  matlab.ui.control.Label
        SimulateButton             matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        tcp % Description
    end
    
    methods (Access = private)
        

        function [x y z n] = Trayectoias_GUI(app,traj,n,tf,dt)
            switch traj
                case 1
                    %close all;
                     R = 1; h = 1; 
                    %=================================================================
                    t = 0.1:dt:tf; x=zeros(n,tf/dt); y=zeros(n,tf/dt);
                    z=h*ones(n,tf/dt);
                    for k=0:n-1
                        x(k+1,:) = R*cos(t+k*2*pi/n);
                        y(k+1,:) = R*sin(t+k*2*pi/n);
                    end
                    
                    %=================================================================
                    app.UIAxes.XLim = (1.9*[-1, 1]);
                    app.UIAxes.YLim = (2.4*[-1, 1]);
                    app.UIAxes.ZLim = ([0, 1.5]);
                    grid on;
                    hold on;
            
                    for k=1:tf/dt
                        scatter3(app.UIAxes,x(:,k),y(:,k),z(:,k));
                        pause(dt);
                    end
            
                case 2
                     R_max = 1; h_max = 1.5; 
                    %=================================================================
                    t = 0.1:dt:tf; x=zeros(n,tf/dt); y=zeros(n,tf/dt); z=zeros(n,tf/dt);
            
                    z(1,:) = h_max; cir_1 = fix(2*(n-1)/3);
                    for k=1:cir_1+2
                        x(k+1,:) = R_max*cos(t+k*2*pi/cir_1);
                        y(k+1,:) = R_max*sin(t+k*2*pi/cir_1);
                        z(k+1,:) = h_max/3;
                    end
            
                    for k=cir_1+1:n-1
                        x(k+1,:) = R_max/2*cos(t+k*2*pi/(n-1-cir_1));
                        y(k+1,:) = R_max/2*sin(t+k*2*pi/(n-1-cir_1));
                        z(k+1,:) = 2*h_max/3;
                    end
            
                    %=================================================================
            
                    subplot(1,2,1);
                    r = 0.01;
                    app.UIAxes.XLim=(1.9*[-1, 1]);
                    app.UIAxes.YLim=(2.4*[-1, 1]);
                    app.UIAxes.ZLim = ([0, 1.5]);
                    grid on;
                    hold on;

                    for k=1:tf/dt
                        scatter3(app.UIAxes,x(:,k),y(:,k),z(:,k));
                        pause(dt);
                    end
            
                case 3
            %=================================================================
                    t = 0.1:dt:tf; x=zeros(n,tf/dt); y=zeros(n,tf/dt); z=zeros(n,tf/dt);
            
                    d = 2/(n-1);
                    for k=0:n-1
                        z(k+1,:) = 0.6*sin(t+k)+1;
                    end
            
                    for k=0:n-1
                        y(k+1,:) = -1+k*d;
                    end
            
                    r = 0.01;
                    app.UIAxes.XLim=(1.9*[-1, 1]);
                    app.UIAxes.YLim=(2.4*[-1, 1]);
                    app.UIAxes.ZLim = ([0, 1.5]);
                    grid on;
                    hold on;
            
                    for k=1:tf/dt
                        scatter3(app.UIAxes,x(:,k),y(:,k),z(:,k));
                        pause(dt);
                    end
            
                case 4
                     R_max = 1.5; A_max = 0.5;
                    %=================================================================
                    t = 0.1:dt:tf; x=zeros(n,tf/dt); y=zeros(n,tf/dt); z=zeros(n,tf/dt);
            
                    cir_1 = fix(2*(n-1)/3);
            
                    z(1,:) = A_max*sin(t)+0.75;
                    for k=1:cir_1+2
                        x(k+1,:) = R_max*cos(k*2*pi/cir_1);
                        y(k+1,:) = R_max*sin(k*2*pi/cir_1);
                        z(k+1,:) = A_max*sin(t+2*pi/3)+1;
                    end
            
                    for k=cir_1+1:n-1
                        x(k+1,:) = R_max/2*cos(k*2*pi/(n-1-cir_1));
                        y(k+1,:) = R_max/2*sin(k*2*pi/(n-1-cir_1));
                        z(k+1,:) = A_max*sin(t+2*pi/6)+1;
                    end
            
            
                    r = 0.01;
                    app.UIAxes.XLim=(1.9*[-1, 1]);
                    app.UIAxes.YLim=(2.4*[-1, 1]);
                    app.UIAxes.ZLim = ([0, 1.5]);
                    grid on;
                    hold on;
            
                    for k=1:tf/dt
                        scatter3(app.UIAxes,x(:,k),y(:,k),z(:,k));
                        pause(dt);
                    end
            
                case 5
                     R_max = 1.5; A_max = 0.5; 
                    %=================================================================
                    t = 0.1:dt:tf; x=zeros(n,tf/dt); y=zeros(n,tf/dt); z=zeros(n,tf/dt);
            
                    cir_1 = fix(2*(n-1)/3);
            
                    z(1,:) = A_max*sin(t)+0.75;
                    for k=1:cir_1+2
                        x(k+1,:) = R_max*cos(t+k*2*pi/cir_1);
                        y(k+1,:) = R_max*sin(t+k*2*pi/cir_1);
                        z(k+1,:) = A_max*sin(t+2*pi/3)+0.75;
                    end
            
                    for k=cir_1+1:n-1
                        x(k+1,:) = R_max/2*cos(t+k*2*pi/(n-1-cir_1));
                        y(k+1,:) = R_max/2*sin(t+k*2*pi/(n-1-cir_1));
                        z(k+1,:) = A_max*sin(t+2*pi/6)+0.75;
                    end
            
            
                    app.UIAxes.XLim=(1.9*[-1, 1]);
                    app.UIAxes.YLim=(2.4*[-1, 1]);
                    app.UIAxes.ZLim = ([0, 1.5]);
            
            
                    for k=1:tf/dt
                        scatter3(app.UIAxes,x(:,k),y(:,k),z(:,k));
                        pause(dt);
                    end
            
                case 6
                     R_max = 1; A_max = 0.5; 
                    %=================================================================
                    t = 0.1:dt:tf; x=zeros(n,tf/dt); y=zeros(n,tf/dt); z=zeros(n,tf/dt);
            
                    for k=0:n-1
                        x(k+1,:) = R_max*cos(t+k*2*pi/n);
                        y(k+1,:) = R_max*sin(t+k*2*pi/n);
                        z(k+1,:) = A_max*x(k+1,:).^2+0.5;
                    end
            
                   app.UIAxes.XLim=(1.9*[-1, 1]);
                    app.UIAxes.YLim=(2.4*[-1, 1]);
                    app.UIAxes.ZLim = ([0, 1.5]);
            
                   for k=1:tf/dt
                        scatter3(app.UIAxes,x(:,k),y(:,k),z(:,k));
                        pause(dt);
                    end
                case 7
                     R_max = 1.5;  A_max = 0.2; 
                    %=================================================================
                    t = 0.1:dt:tf; x=zeros(n,tf/dt); y=zeros(n,tf/dt); z=zeros(n,tf/dt);
            
                    for k=0:n-1
                        x(k+1,:) = 0.75*R_max*cos(t+k*2*pi/n);
                        y(k+1,:) = R_max*sin(t+k*2*pi/n);
                        z(k+1,:) = -1.25*A_max*y(k+1,:)+1;
                    end
            
                    app.UIAxes.XLim=(1.9*[-1, 1]);
                    app.UIAxes.YLim=(2.4*[-1, 1]);
                    app.UIAxes.ZLim = ([0, 1.5]);
            
                   for k=1:tf/dt
                        scatter3(app.UIAxes,x(:,k),y(:,k),z(:,k));
                        pause(dt);
                    end
                    
                otherwise
                    x=zeros(n,tf/dt); y=zeros(n,tf/dt); z=ones(n,tf/dt);
            end
                X = x; Y = y; Z = z; N = n;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            traj = str2double(string(app.TrajectoryDropDown.Value));
            n = str2double(string(app.CFEditField.Value));
            tf = str2double(string(app.TimeEditField.Value));
            dt = 0.1;
            [x,y,z,n] = app.Trayectoias_GUI(traj,n,tf,dt);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            %app.UITable.Data = zeros(10,numel(app.UITable.ColumnName));
            app.UITable.Data = zeros(10,4);
            app.UITable.Data(1:10,1) = [1,2,3,4,5,6,7,8,9,10];
            try
                app.tcp = crazyswarm_connect();
                app.Lamp.Color = 'green'; 
                
            catch
                msgbox("Could not connect to server","Error")
            end
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            %Nodos = zeros(1,10);
            cbox = [app.CheckBox,app.CheckBox_2,...
                    app.CheckBox_3,app.CheckBox_4,...
                    app.CheckBox_5,app.CheckBox_6,...
                    app.CheckBox_7,app.CheckBox_8,...
                    app.CheckBox_9,app.CheckBox_10,];
            if(app.PointsButton.Value==1)
                for i=1:10
                    data = cbox(i);
                    if(data.Value==1)
                        x = app.UITable.Data(i,2);
                        y = app.UITable.Data(i,3);
                        z = app.UITable.Data(i,4);
                        x_y_z = [x y z];
                        crazyswarm_goto(app.tcp,i,x_y_z)
                        %disp(x_y_z)
                    end
                end
            else
                traj = str2double(string(app.TrajectoryDropDown.Value));
                n = str2double(string(app.CFEditField.Value));
                tf = str2double(string(app.TimeEditField.Value));
                dt = 1;
                %disp(traj); disp(n); disp(tf); disp(dt);
                [x,y,z,n] = app.Trayectoias_GUI(traj,n,tf,dt);
                crazyswarm_traj(app.tcp,n,x,y,z);
            end
        end

        % Button pushed function: LandButton
        function LandButtonPushed(app, event)
            cbox = [app.CheckBox,app.CheckBox_2,...
                app.CheckBox_3,app.CheckBox_4,...
                app.CheckBox_5,app.CheckBox_6,...
                app.CheckBox_7,app.CheckBox_8,...
                app.CheckBox_9,app.CheckBox_10,];
            for i=1:10
                data = cbox(i);
                if(data.Value==1)
                    n = vertcat(n,i);
                end
                crazyswarm_land(app.tcp,n);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.ROBOTATCRAZYSWARMGUILabel = uilabel(app.UIFigure);
            app.ROBOTATCRAZYSWARMGUILabel.Text = 'ROBOTAT CRAZYSWARM GUI';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = {'Connect'; ''};
            app.Lamp = uilamp(app.UIFigure);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'CF'; 'X(m)'; 'Y(m)'; 'Z(m)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true true];
            app.GOButton = uibutton(app.UIFigure, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = {'GO'; ''};
            app.Tree = uitree(app.UIFigure);
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Text = '1';
            app.CFEnabledLabel = uilabel(app.UIFigure);
            app.CFEnabledLabel.Text = 'CF Enabled';
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.Text = '2';
            app.CheckBox_3 = uicheckbox(app.UIFigure);
            app.CheckBox_3.Text = '3';
            app.CheckBox_4 = uicheckbox(app.UIFigure);
            app.CheckBox_4.Text = '4';
            app.CheckBox_5 = uicheckbox(app.UIFigure);
            app.CheckBox_5.Text = '5';
            app.CheckBox_6 = uicheckbox(app.UIFigure);
            app.CheckBox_6.Text = '6';
            app.CheckBox_7 = uicheckbox(app.UIFigure);
            app.CheckBox_7.Text = '7';
            app.CheckBox_8 = uicheckbox(app.UIFigure);
            app.CheckBox_8.Text = '8';
            app.CheckBox_9 = uicheckbox(app.UIFigure);
            app.CheckBox_9.Text = '9';
            app.CheckBox_10 = uicheckbox(app.UIFigure);
            app.CheckBox_10.Text = '10';
            app.LandButton = uibutton(app.UIFigure, 'push');
            app.LandButton.ButtonPushedFcn = createCallbackFcn(app, @LandButtonPushed, true);
            app.LandButton.Text = 'Land';
            app.SendButtonGroup = uibuttongroup(app.UIFigure);
            app.SendButtonGroup.Title = 'Send';
            app.TrajectoryButton = uiradiobutton(app.SendButtonGroup);
            app.TrajectoryButton.Text = 'Trajectory';
            app.TrajectoryButton.Value = true;
            app.PointsButton = uiradiobutton(app.SendButtonGroup);
            app.PointsButton.Text = 'Points';
            app.TrajectoryDropDownLabel = uilabel(app.UIFigure);
            app.TrajectoryDropDownLabel.Text = 'Trajectory';
            app.TrajectoryDropDown = uidropdown(app.UIFigure);
            app.TrajectoryDropDown.Items = {'1', '2', '3', '4', '5', '6', '7'};
            app.TrajectoryDropDown.Value = '1';
            app.CFEditFieldLabel = uilabel(app.UIFigure);
            app.CFEditFieldLabel.Text = '# CF';
            app.CFEditField = uieditfield(app.UIFigure, 'numeric');
            app.CFEditField.Value = 1;
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeEditField.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end